classdef calibApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CaptureTab                     matlab.ui.container.Tab
        CameraControlPanel             matlab.ui.container.Panel
        BrightnessSliderLabel          matlab.ui.control.Label
        BrightnessSlider               matlab.ui.control.Slider
        ContrastSliderLabel            matlab.ui.control.Label
        ContrastSlider                 matlab.ui.control.Slider
        ExposureSliderLabel            matlab.ui.control.Label
        ExposureSlider                 matlab.ui.control.Slider
        GainSliderLabel                matlab.ui.control.Label
        GainSlider                     matlab.ui.control.Slider
        GammaSliderLabel               matlab.ui.control.Label
        GammaSlider                    matlab.ui.control.Slider
        HueSliderLabel                 matlab.ui.control.Label
        HueSlider                      matlab.ui.control.Slider
        SaturationSliderLabel          matlab.ui.control.Label
        SaturationSlider               matlab.ui.control.Slider
        SharpnessSliderLabel           matlab.ui.control.Label
        SharpnessSlider                matlab.ui.control.Slider
        WhiteBalanceSliderLabel        matlab.ui.control.Label
        WhiteBalanceSlider             matlab.ui.control.Slider
        ResetButton                    matlab.ui.control.Button
        ExposureModeDropDownLabel      matlab.ui.control.Label
        ExposureModeDropDown           matlab.ui.control.DropDown
        WhiteBalanceModeDropDownLabel  matlab.ui.control.Label
        WhiteBalanceModeDropDown       matlab.ui.control.DropDown
        BacklightCompensationDropDownLabel  matlab.ui.control.Label
        BacklightCompensationDropDown  matlab.ui.control.DropDown
        FixtomanualLabel               matlab.ui.control.Label
        FixtooffLabel                  matlab.ui.control.Label
        CameraDropDownLabel            matlab.ui.control.Label
        CameraDropDown                 matlab.ui.control.DropDown
        ResolutionDropDownLabel        matlab.ui.control.Label
        ResolutionDropDown             matlab.ui.control.DropDown
        ProjecionScreenDropDownLabel   matlab.ui.control.Label
        ProjecionScreenDropDown        matlab.ui.control.DropDown
        CaptureButton                  matlab.ui.control.Button
        PreviewPanel                   matlab.ui.container.Panel
        ProUIAxes                      matlab.ui.control.UIAxes
        CamLigthtGridUIAxes            matlab.ui.control.UIAxes
        CamColorGridUIAxes             matlab.ui.control.UIAxes
        SetdatarootButton              matlab.ui.control.Button
        DatafolderLabel                matlab.ui.control.Label
        CalibrationTab                 matlab.ui.container.Tab
        ImagesPanel                    matlab.ui.container.Panel
        SelectAllButton                matlab.ui.control.Button
        BrowseButton                   matlab.ui.control.Button
        ImageListBox                   matlab.ui.control.ListBox
        PreviewPanel_2                 matlab.ui.container.Panel
        CalibUIAxes                    matlab.ui.control.UIAxes
        PreviewImageTypeButtonGroup    matlab.ui.container.ButtonGroup
        lightGridButton                matlab.ui.control.RadioButton
        colorGridButton                matlab.ui.control.RadioButton
        CalibrateButton                matlab.ui.control.Button
        ReconstructionTab              matlab.ui.container.Tab
    end

    
    properties (Access = private)
        cam % webcam object used to capture image
        camList % a list of connected cameras that are recognized by matlab
        camInfo % all cameras' properties, such as birghtnees, exposure etc.
        camSliderProperties
        camDropDownProperties
        camPreviewImg % screenshot of the camera image
        camPreviewFig % camera preivew figure
        
        imPattern
        screenSize
        prjFigHandel
        
        dataRoot
        dataName
        imgCounter = 1 % count how many images captured so far
        
        calibInfo % calibration info
        dataFolder % calibration data folder
        previewCalibImageType = 'lightGrid' % Description
    end


    methods (Access = public)
        
        function initCameras(app)
            %             camList = imaqhwinfo('winvideo'); % windows
            %             camList = imaqhwinfo('macvideo'); % linux
            %             camList = imaqhwinfo('linuxvideo'); % mac
            
            %             for i = numel(camList)
            %                 curDevice = imaqhwinfo(adaptorList.InstalledAdaptors{i});
            %
            %             end
            
            
            app.camList = webcamlist();
            
            % error if camera not found
            if(numel(app.camList) == 0)
                f = uifigure;
                uialert(f, 'Camera not found','Error');
            else
                app.CameraDropDown.Items = app.camList;
                
                % get camera infos, such as supported register values
                getCamInfo(app);
                % display camera names in dropdown list
                %                 app.CameraDropDown.Items = {camList.DeviceInfo.DeviceName};
                %                 app.CameraDropDown.ItemsData = 1:numel(app.CameraDropDown.Items);
                %                 app.CameraDropDown.Items = {camList.DeviceInfo.DeviceName};
                
                %                 app.CameraDropDown.Value = camList{1};
                
                %                 app.ResolutionDropDown.Items =
                
                % start cam
                app.cam = webcam(app.camList{1});
                updateCamResolutionDropDown(app);
                
                app.camSliderProperties = {'Brightness', 'Contrast', 'Exposure', 'Gain', 'Gamma', 'Hue', 'Saturation', 'Sharpness', 'WhiteBalance'};
                app.camDropDownProperties = {'BacklightCompensation', 'ExposureMode', 'WhiteBalanceMode'};
                
                updateCamControlPannel(app, 1);
                %                 imFrame = snapshot(app.cam);
                %                                 app.CamLigthtGridUIAxes.PlotBoxAspectRatio = [size(imFrame, 2), size(imFrame, 1), size(imFrame, 1)];
                %                                 imPreview = image(app.CamLigthtGridUIAxes, zeros(size(imFrame), 'uint8'));
                app.camPreviewImg = preview(app.cam);
                
            end
            
        end
        
        %% update the camera resolution dropdown list
        function updateCamResolutionDropDown(app)
            app.ResolutionDropDown.Items = app.cam.AvailableResolutions;
            app.ResolutionDropDown.Value = app.cam.Resolution;
        end
        
        function getCamInfo(app)
            f = waitbar(0, 'Scanning your webcams...');
            for camID = 1:numel(app.camList)
                waitbar(camID/numel(app.camList), f, [num2str(camID), ' webcam discovered...']);
                src = getselectedsource(videoinput('winvideo', camID)); % slow
                app.camInfo{camID} = src.propinfo;
                
                % set ExposureMode to manual and BacklightCompensation to off, since our calibration algorithm requires the lightGrid and colorGrid images have the same background brightness
                if(isfield(app.camInfo{camID}, 'ExposureMode'))
                    app.camInfo{camID}.ExposureMode.DefaultValue = 'manual';
                end
                
                if(isfield(app.camInfo{camID}, 'BacklightCompensation'))
                    app.camInfo{camID}.BacklightCompensation.DefaultValue = 'off';
                end
            end
            
            close(f);
        end
        
        function updateCamControlPannel(app, camID)
            initCamSliders(app, camID);
            initCamDropDowns(app, camID);
            resetCamDropDowns(app, camID);
        end
   
        
        function getScreenInfo(app)
            app.screenSize = round(get(0, 'MonitorPositions'));
            numMonitors = size(app.screenSize, 1);
            
            for i=1:numMonitors
                app.ProjecionScreenDropDown.Items{i} = ['[Screen: ', num2str(i), '] ', num2str(app.screenSize(i,3)), 'x', num2str(app.screenSize(i,4))];
            end
            
            app.ProjecionScreenDropDown.ItemsData = 1:numMonitors;
            
            % init projector image
            screenID = app.ProjecionScreenDropDown.Value;            
            app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4));
            previewImage(app, 'ProUIAxes', app.imPattern);
        end
        
        function figHandle = initPrjWindow(app, screenID, im)
%             app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4));
            figHandle = figure('MenuBar', 'none', 'ToolBar', 'none');
            imshow(im);
            
            axeHandle = gca;
            set(axeHandle, 'Position', [0 0 1 1])
            set(figHandle, 'Position', app.screenSize(screenID,:))
            set(figHandle, 'WindowState', 'fullscreen')
            
            % speedup projection
            set(gca, 'xlimmode','manual',...
                'ylimmode','manual',...
                'zlimmode','manual',...
                'climmode','manual',...
                'alimmode','manual');
            set(figHandle,'doublebuffer','off');
        end
    end
    
    methods (Access = private)
        
        function initCamSliders(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            for i = 1:numel(app.camSliderProperties)
                propertyName = app.camSliderProperties{i};
                sliderName = [propertyName, 'Slider'];
                if(any(contains(propertyNames, propertyName)))
                    
                    app.(sliderName).Visible = true;
                    app.(sliderName).Limits = double(app.camInfo{camID}.(propertyName).ConstraintValue);
                    app.(sliderName).Value = app.cam.(propertyName);
                    app.(sliderName).Tag = propertyName; % for later use
                else
                    app.(sliderName).Visible = false;
                end
            end
            
        end
        
        function resetCamSliders(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            for i = 1:numel(app.camSliderProperties)
                propertyName = app.camSliderProperties{i};
                sliderName = [propertyName, 'Slider'];
                
                if(any(contains(propertyNames, propertyName)))
                    
                    app.(sliderName).Value = double(app.camInfo{camID}.(propertyName).DefaultValue);
                    app.cam.(propertyName) = app.(sliderName).Value;
                    
                end
            end
            
        end
        
        function initCamDropDowns(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            
            for i = 1:numel(app.camDropDownProperties)
                propertyName = app.camDropDownProperties{i};
                dropDownName = [propertyName, 'DropDown'];
                
                if(any(contains(propertyNames, propertyName)))                    
                    app.(dropDownName).Items = app.camInfo{camID}.(propertyName).ConstraintValue;
                    app.(dropDownName).Tag = propertyName; % for later use
                    
                    % matlab image aquisition toolbox's BacklightCompensation is string, while webcam's is 0 or 1
                    if(strcmp(propertyName, 'BacklightCompensation'))
                        if(app.cam.(propertyName) == 1)
                            app.(dropDownName).Value = 'on';
                        else
                            app.(dropDownName).Value = 'off';
                        end
                    else
                        app.(dropDownName).Value = app.cam.(propertyName);
                    end
                    
                    app.(dropDownName).Visible = true;
                else
                    app.(dropDownName).Visible = false;
                end
            end
            
        end
        
        
        function resetCamDropDowns(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            for i = 1:numel(app.camDropDownProperties)
                propertyName = app.camDropDownProperties{i};
                dropDownName = [propertyName, 'DropDown'];
                
                if(any(contains(propertyNames, propertyName)))                    
                    app.(dropDownName).Value = app.camInfo{camID}.(propertyName).DefaultValue;
                    
                    try
                        if(strcmp(propertyName, 'BacklightCompensation'))
                            app.cam.(propertyName) =  strcmp(app.(dropDownName).Value, 'on');
                        else
                            app.cam.(propertyName) = app.(dropDownName).Value;
                        end
                    catch e
                        disp(e) % for debug
                    end
                end
            end
            
        end
        
                
        function projectImage(app, im)
            screenID = app.ProjecionScreenDropDown.Value;
            
            if(isempty(app.prjFigHandel))
                app.prjFigHandel = initPrjWindow(app, screenID, im);
            else
                cla;
                set(app.prjFigHandel, 'WindowState', 'normal');
%                 app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4));
                imshow(im);
                
                axisHandle = gca;
                set(axisHandle, 'Position', [0 0 1 1]);
                
                set(app.prjFigHandel, 'Position', app.screenSize(screenID,:))
                set(app.prjFigHandel, 'WindowState', 'fullscreen');
                
            end
        end
        
        % preview the input image in a axis specified by its name
        function previewImage(app, axisName, im)
            app.(axisName).PlotBoxAspectRatio = [size(im, 2), size(im, 1), size(im, 1)];
            app.(axisName).XLim = [0 size(im, 2)];
            app.(axisName).YLim = [0 size(im, 1)];
            image(app.(axisName), im);
        end
        
        
        function initDataRoot(app)
            app.dataRoot = pwd;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initDataRoot(app);
            initCameras(app);
            getScreenInfo(app);


        end

        % Value changed function: CameraDropDown
        function CameraDropDownValueChanged(app, event)
            camName = app.CameraDropDown.Value;
            camID = find(contains(app.CameraDropDown.Items, camName));
            app.cam = webcam(camName);
            updateCamControlPannel(app, camID);
            
            app.camPreviewImg = preview(app.cam);
            updateCamResolutionDropDown(app);
            
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            screenID = app.ProjecionScreenDropDown.Value;
            
            % project pure black image and capture imLightGrid 
            imBlack = zeros(app.screenSize(screenID, 4), app.screenSize(screenID, 3), 3); % in case desktop provides some lighting, we cover the fullscreen with a black image
            projectImage(app, imBlack);
            pause(0.5);
            imLightGrid = app.camPreviewImg.CData;
            previewImage(app, 'CamLigthtGridUIAxes', imLightGrid);            
            
            % project color grid and capture imColorGrid
            app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4));
            projectImage(app, app.imPattern);
            pause(0.5);
            imColorGrid = app.camPreviewImg.CData;
            previewImage(app, 'CamColorGridUIAxes', imColorGrid);
            
            % delete color grid figure
            delete(app.prjFigHandel);
            app.prjFigHandel = [];

            % save captured images
            imwrite(imLightGrid, fullfile(app.dataRoot, app.dataName, ['lightGrid', sprintf('%02d', app.imgCounter), '.png']));
            imwrite(imColorGrid, fullfile(app.dataRoot, app.dataName, ['colorGrid', sprintf('%02d', app.imgCounter), '.png']));
            app.imgCounter = app.imgCounter+1;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.prjFigHandel);
            delete(app);
            
        end

        % Value changed function: ResolutionDropDown
        function ResolutionDropDownValueChanged(app, event)
            resolution = app.ResolutionDropDown.Value;
            app.cam.Resolution = resolution;
            app.camPreviewImg = app.cam.preview;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            camID = find(contains(app.CameraDropDown.Items, app.CameraDropDown.Value));
            
            resetCamSliders(app, camID);
            resetCamDropDowns(app, camID);
                     
        end

        % Value changed function: BacklightCompensationDropDown, 
        % ExposureModeDropDown, WhiteBalanceModeDropDown
        function camDropDownValueChanged(app, event)
            propertyName = event.Source.Tag; % this is set when this component is initialized, mannually
            
            try
                if(strcmp(propertyName, 'BacklightCompensation'))
                    app.cam.BacklightCompensation = strcmp(app.BacklightCompensationDropDown.Value, 'on');
                else
                    app.cam.(propertyName) = app.([propertyName, 'DropDown']).Value;
                end
            catch e
                disp(e) % for debug
            end
            
        end

        % Value changed function: ProjecionScreenDropDown
        function ProjecionScreenDropDownValueChanged(app, event)
            screenID = app.ProjecionScreenDropDown.Value;
%             
%             if(isempty(app.prjFigHandel))
%                 app.prjFigHandel = initPrjWindow(app, screenID);
%             else
%                 cla;
%                 set(app.prjFigHandel, 'WindowState', 'normal');
%                 app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4));
%                 imshow(app.imPattern);
%                 
%                 axisHandle = gca;
%                 set(axisHandle, 'Position', [0 0 1 1]);
%                 
%                 set(app.prjFigHandel, 'Position', app.screenSize(screenID,:))
%                 set(app.prjFigHandel, 'WindowState', 'fullscreen');
%                 
%             end
            app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4));
            previewImage(app, 'ProUIAxes', app.imPattern);
        end

        % Callback function: BrightnessSlider, ContrastSlider, 
        % ExposureSlider, GainSlider, GammaSlider, HueSlider, 
        % SaturationSlider, SharpnessSlider, WhiteBalanceSlider
        function camSliderValueChanged(app, event)
            propertyName = event.Source.Tag; % this is set when this component is initialized, mannually
            
            try
                app.cam.(propertyName) = app.([propertyName, 'Slider']).Value;
            catch e
                disp(e) % for debug
            end
        end

        % Button pushed function: SetdatarootButton
        function SetdatarootButtonPushed(app, event)
            app.dataRoot = uigetdir(app.dataRoot, 'Select where you want to save the capture data');
            app.dataName = ['calibration-', datestr(now,'mm-dd-yy__HH-MM-SS')];
            mkdir(fullfile(app.dataRoot,app.dataName));
            
            app.DatafolderLabel.Text = ['Data folder: ', fullfile(app.dataRoot,app.dataName)];
            
            app.imgCounter = 1; % reset image counter
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.dataFolder = uigetdir(app.dataRoot, 'Select where calibration data is stored');
            
            setList = dir(fullfile(app.dataFolder, '*light*.png'));
            
%             imgList
            for i=1:numel(setList)
                fileName = setList(i).name;
                app.ImageListBox.Items{i} = ['Set', fileName(end-5:end-4)];
            end
%             app.ImageListBox.Items = {imgList.name};
        end

        % Value changed function: ImageListBox
        function ImageListBoxValueChanged(app, event)
            imgName = app.ImageListBox.Value;
            im = imread(fullfile(app.dataFolder, [app.previewCalibImageType, imgName{1}(end-1:end), '.png']));
            previewImage(app, 'CalibUIAxes', im);
            
        end

        % Selection changed function: PreviewImageTypeButtonGroup
        function PreviewImageTypeButtonGroupSelectionChanged(app, event)
            app.previewCalibImageType = app.PreviewImageTypeButtonGroup.SelectedObject.Text;
            imgName = app.ImageListBox.Value;
            im = imread(fullfile(app.dataFolder, [app.previewCalibImageType, imgName{1}(end-1:end), '.png']));
            previewImage(app, 'CalibUIAxes', im);
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.ImageListBox.Value = app.ImageListBox.Items;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CaptureTab = uitab(app.TabGroup);
            app.CaptureTab.Title = 'Capture';
            app.CameraControlPanel = uipanel(app.CaptureTab);
            app.CameraControlPanel.Title = 'Camera Control';
            app.BrightnessSliderLabel = uilabel(app.CameraControlPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.CameraControlPanel);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.ContrastSliderLabel = uilabel(app.CameraControlPanel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.CameraControlPanel);
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.ExposureSliderLabel = uilabel(app.CameraControlPanel);
            app.ExposureSliderLabel.Text = 'Exposure';
            app.ExposureSlider = uislider(app.CameraControlPanel);
            app.ExposureSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.GainSliderLabel = uilabel(app.CameraControlPanel);
            app.GainSliderLabel.Text = 'Gain';
            app.GainSlider = uislider(app.CameraControlPanel);
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.GammaSliderLabel = uilabel(app.CameraControlPanel);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.CameraControlPanel);
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.HueSliderLabel = uilabel(app.CameraControlPanel);
            app.HueSliderLabel.Text = 'Hue';
            app.HueSlider = uislider(app.CameraControlPanel);
            app.HueSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.SaturationSliderLabel = uilabel(app.CameraControlPanel);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.CameraControlPanel);
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.SharpnessSliderLabel = uilabel(app.CameraControlPanel);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.SharpnessSlider = uislider(app.CameraControlPanel);
            app.SharpnessSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.WhiteBalanceSliderLabel = uilabel(app.CameraControlPanel);
            app.WhiteBalanceSliderLabel.Text = 'WhiteBalance';
            app.WhiteBalanceSlider = uislider(app.CameraControlPanel);
            app.WhiteBalanceSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.ResetButton = uibutton(app.CameraControlPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExposureModeDropDownLabel = uilabel(app.CameraControlPanel);
            app.ExposureModeDropDownLabel.Text = 'ExposureMode';
            app.ExposureModeDropDown = uidropdown(app.CameraControlPanel);
            app.ExposureModeDropDown.Items = {};
            app.ExposureModeDropDown.ValueChangedFcn = createCallbackFcn(app, @camDropDownValueChanged, true);
            app.ExposureModeDropDown.Value = {};
            app.WhiteBalanceModeDropDownLabel = uilabel(app.CameraControlPanel);
            app.WhiteBalanceModeDropDownLabel.Text = 'WhiteBalanceMode';
            app.WhiteBalanceModeDropDown = uidropdown(app.CameraControlPanel);
            app.WhiteBalanceModeDropDown.Items = {};
            app.WhiteBalanceModeDropDown.ValueChangedFcn = createCallbackFcn(app, @camDropDownValueChanged, true);
            app.WhiteBalanceModeDropDown.Value = {};
            app.BacklightCompensationDropDownLabel = uilabel(app.CameraControlPanel);
            app.BacklightCompensationDropDownLabel.Text = 'BacklightCompensation';
            app.BacklightCompensationDropDown = uidropdown(app.CameraControlPanel);
            app.BacklightCompensationDropDown.Items = {};
            app.BacklightCompensationDropDown.ValueChangedFcn = createCallbackFcn(app, @camDropDownValueChanged, true);
            app.BacklightCompensationDropDown.Value = {};
            app.FixtomanualLabel = uilabel(app.CameraControlPanel);
            app.FixtomanualLabel.Text = 'Fix to ''manual'' ';
            app.FixtooffLabel = uilabel(app.CameraControlPanel);
            app.FixtooffLabel.Text = 'Fix to ''off'' ';
            app.CameraDropDownLabel = uilabel(app.CaptureTab);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.CaptureTab);
            app.CameraDropDown.Items = {};
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.CameraDropDown.Value = {};
            app.ResolutionDropDownLabel = uilabel(app.CaptureTab);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.CaptureTab);
            app.ResolutionDropDown.Items = {};
            app.ResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionDropDownValueChanged, true);
            app.ResolutionDropDown.Value = {};
            app.ProjecionScreenDropDownLabel = uilabel(app.CaptureTab);
            app.ProjecionScreenDropDownLabel.Text = 'Projecion Screen';
            app.ProjecionScreenDropDown = uidropdown(app.CaptureTab);
            app.ProjecionScreenDropDown.Items = {};
            app.ProjecionScreenDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjecionScreenDropDownValueChanged, true);
            app.ProjecionScreenDropDown.Value = {};
            app.CaptureButton = uibutton(app.CaptureTab, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.PreviewPanel = uipanel(app.CaptureTab);
            app.PreviewPanel.Title = 'Preview';
            app.ProUIAxes = uiaxes(app.PreviewPanel);
            title(app.ProUIAxes, 'Projected color grid')
            app.ProUIAxes.XTick = [];
            app.ProUIAxes.XTickLabel = '';
            app.ProUIAxes.YTick = [];
            app.ProUIAxes.YTickLabel = '';
            app.CamLigthtGridUIAxes = uiaxes(app.PreviewPanel);
            title(app.CamLigthtGridUIAxes, 'Camera light grid image')
            app.CamLigthtGridUIAxes.XTick = [];
            app.CamLigthtGridUIAxes.XTickLabel = {};
            app.CamLigthtGridUIAxes.YTick = [];
            app.CamLigthtGridUIAxes.YTickLabel = {};
            app.CamColorGridUIAxes = uiaxes(app.PreviewPanel);
            title(app.CamColorGridUIAxes, 'Camera color grid image')
            app.CamColorGridUIAxes.XTick = [];
            app.CamColorGridUIAxes.XTickLabel = '';
            app.CamColorGridUIAxes.YTick = [];
            app.CamColorGridUIAxes.YTickLabel = '';
            app.SetdatarootButton = uibutton(app.CaptureTab, 'push');
            app.SetdatarootButton.ButtonPushedFcn = createCallbackFcn(app, @SetdatarootButtonPushed, true);
            app.SetdatarootButton.Text = 'Set data root';
            app.DatafolderLabel = uilabel(app.CaptureTab);
            app.DatafolderLabel.Text = 'Data folder: ';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.ImagesPanel = uipanel(app.CalibrationTab);
            app.ImagesPanel.Title = 'Images';
            app.SelectAllButton = uibutton(app.ImagesPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.BrowseButton = uibutton(app.ImagesPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ImageListBox = uilistbox(app.CalibrationTab);
            app.ImageListBox.Items = {};
            app.ImageListBox.Multiselect = 'on';
            app.ImageListBox.ValueChangedFcn = createCallbackFcn(app, @ImageListBoxValueChanged, true);
            app.ImageListBox.Value = {};
            app.PreviewPanel_2 = uipanel(app.CalibrationTab);
            app.PreviewPanel_2.Title = 'Preview';
            app.CalibUIAxes = uiaxes(app.PreviewPanel_2);
            title(app.CalibUIAxes, '')
            app.CalibUIAxes.XTick = [];
            app.CalibUIAxes.XTickLabel = '';
            app.CalibUIAxes.YTick = [];
            app.CalibUIAxes.YTickLabel = '';
            app.PreviewImageTypeButtonGroup = uibuttongroup(app.CalibrationTab);
            app.PreviewImageTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PreviewImageTypeButtonGroupSelectionChanged, true);
            app.PreviewImageTypeButtonGroup.Title = 'PreviewImageType';
            app.lightGridButton = uiradiobutton(app.PreviewImageTypeButtonGroup);
            app.lightGridButton.Text = 'lightGrid';
            app.lightGridButton.Value = true;
            app.colorGridButton = uiradiobutton(app.PreviewImageTypeButtonGroup);
            app.colorGridButton.Text = 'colorGrid';
            app.CalibrateButton = uibutton(app.CalibrationTab, 'push');
            app.CalibrateButton.Text = 'Calibrate';
            app.ReconstructionTab = uitab(app.TabGroup);
            app.ReconstructionTab.Title = 'Reconstruction';
        end
    end

    methods (Access = public)

        % Construct app
        function app = calibApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end